classdef GUI_AGA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        LeftPanel                matlab.ui.container.Panel
        GridLayoutLeft           matlab.ui.container.GridLayout
        GridLayoutRunStop        matlab.ui.container.GridLayout
        StopButton               matlab.ui.control.Button
        RunButton                matlab.ui.control.Button
        GridLayoutControls       matlab.ui.container.GridLayout
        ColorScaleSwitch         matlab.ui.control.Switch
        ColorScaleSwitchLabel    matlab.ui.control.Label
        FitScaleSwitch           matlab.ui.control.Switch
        FitScaleSwitchLabel      matlab.ui.control.Label
        NewcomersSlider          matlab.ui.control.Slider
        NewcomersSliderLabel     matlab.ui.control.Label
        ParentsSlider            matlab.ui.control.Slider
        ParentsSliderLabel       matlab.ui.control.Label
        MutantsSlider            matlab.ui.control.Slider
        MutantsSliderLabel       matlab.ui.control.Label
        ElitesSpinner            matlab.ui.control.Spinner
        ElitesSpinnerLabel       matlab.ui.control.Label
        PopulationSpinner        matlab.ui.control.Spinner
        PopulationSpinnerLabel   matlab.ui.control.Label
        GenerationsSpinner       matlab.ui.control.Spinner
        GenerationsSpinnerLabel  matlab.ui.control.Label
        FunctionDropDown         matlab.ui.control.DropDown
        FunctionDropDownLabel    matlab.ui.control.Label
        RightPanel               matlab.ui.container.Panel
        GridLayoutRight          matlab.ui.container.GridLayout
        UIAxesFit                matlab.ui.control.UIAxes
        UIAxes2D                 matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    % Class properties
    properties (Access = private)

        % Currently selected fitness function (CSFF)
        fitfunh % CSFF function handle
        fitbnds % Boundaries for CSFF
        fitopts % Optimum points for CSFF
        fitmaxv % Maximum value for CSFF
        fitminv % Minimum value for CSFF

        % Plot handles
        ph_best % Best theoretical point(s) for CSFF
        ph_leg % Legend for CSFF map
        ph_fit % CSFF values map
        ph_pop % Results: population individuals
        ph_fi_max % Results: fitness max
        ph_fi_min % Results: fitness min
        ph_fi_std % Results: fitness std

        % Controls
        stop_simulation % Trigger to stop current simulation

    end
    
    % Optimisers
    methods (Access = private)

        % AGA: A Genetic Algorithm
        function res = aga(app, goal, ng, np, ne, nm, nn, na)

            % Create results struct
            res.bestind = []; % Best individual
            res.bestfit = []; % Best individual's fitness
            res.lastite = []; % Last iteration index

            % Build population
            pop = cell(1,np); % Preallocate population variable
            for i=1:np % Fill population
                pop{i} = app.ranfun(); % Generate random individual
            end

            % Number of descendants
            % >> elites + mutants + newcomers --> the rest are descendants
            nd = np - ne - nm - nn; 

            % Iterate through generations
            for g=1:ng

                % Preallocate/clear fitness array
                fi = zeros(np,1); 

                % Clean-up population: remove repeated individuals
                [pop,fi] = app.unifun(pop,fi); % Return unique individuals
                idx = cellfun('isempty',pop); % Empty individuals indexes
                pop = pop(~idx); % Remove empty individuals
                fi = fi(~idx); % Remove empty fitness
                ncleanpop = length(pop); % Size of clean population

                % Avoid population degeneration (i.e., poor genetic pool)
                if ncleanpop<na % Population size is less than breeders size
                    res.bestind = pop{1}; % Save best individual
                    res.bestfit = fi(1); % Save fitness of best individual
                    res.lastite = g; % Save current generation index
                    fprintf('AGA degenerate population; leaving.\n');
                    break; % Stop iterating
                end

                % Repopulation: fill population pool with new individuals
                for i=ncleanpop+1:np % Fill up to initial population size
                    pop{i} = app.ranfun(); % Create new random individual
                end

                % Evaluate fitness function
                for i=1:np
                    fi(i) = app.fitfun(pop{i});
                end

                % Sort population individuals by their fitness level
                [fi,i] = sort(fi); % Sort (lower is best)
                pop = pop(i); % Sort population by fitness

                % Plot current generation info in GUI
                if ~app.stop_simulation
                    app.plot_population(pop, fi, g, ne, nm, nd);
                    app.plot_fitness(g, fi);
                end

                % Check: target fitness | max generations | stop 
                if fi(1)<=goal || g>=ng || app.stop_simulation
                    res.bestind = pop{1}; % Save best individual
                    res.bestfit = fi(1); % Save best fitness level
                    res.lastite = g; % Save current generation index
                    fprintf('AGA nite=%2d fitbest=%f',g,res.bestfit);
                    fprintf(' best='); app.prifun(res.bestind);
                    if res.bestfit<goal % Goal achieved
                        fprintf(' goal=%e achieved; leaving\n',goal);
                    elseif g>=ng % Maximum generations reached
                        fprintf(' max. iterations reached; leaving\n');
                    elseif app.stop_simulation
                        fprintf(' simulation stopped by user; leaving\n');
                    end
                    break; % Stop iterating
                end

                % Show current generation info
                fprintf('AGA g=%2d fitbest=%f',g,fi(1));
                fprintf(' best='); app.prifun(pop{1});
                fprintf('\n');

                % Compute population for next generation:
                % <<[elites, mutants, descendants, newcomers]<<
                nextpop = cell(1,ne+nm+nn+nd); % Preallocate next population
                k = 1; % Start individual counter index

                for i=1:ne % Elites
                    nextpop{k} = pop{k}; % Copy elite into next generation
                    k = k + 1; % Next individual
                end

                for i=1:nm % Mutants
                    nextpop{k} = app.mutfun(pop{k},fi(k)); % Mutate
                    k = k + 1; % Next individual
                end

                for i=1:nd % Descendants
                    parentA = randi([1,na]); % Parent A is chosen among the na best 
                    parentB = randi([1,na]); % Parent B is chosen among the na best 
                    nextpop{k} = app.repfun(pop{parentA}, pop{parentB}, ...
                        fi(parentA), fi(parentB)); % Breed individuals A and B
                    k = k + 1; % Next individual
                end

                for i=1:nn % Newcommers
                    nextpop{k} = app.ranfun(); % Random individual
                    k = k + 1; % Next individual
                end

                % Update population
                pop = nextpop;

            end

        end

    end

    % Optimiser auxiliary functions
    methods (Access = private)

        % Fitness function
        function fi = fitfun(app, x)
            fi = app.fitfunh(x(1), x(2));
        end

        % Mutation function
        function y = mutfun(app, x, ~)
            [~,dx,~, ~,dy,~] = app.getbounds();
            xx = app.ranrange(-dx, dx, 1) * 10;
            yy = app.ranrange(-dy, dy, 1) * 10;
            y = x + [xx; yy];
        end

        % Reporduction function
        function y = repfun(~, x, y, ~, ~)
            y = (x + y) / 2;
        end

        % Random function
        function y = ranfun(app)
            [x1,~,x2, y1,~,y2] = app.getbounds();
            xx = app.ranrange(x1, x2, 1);
            yy = app.ranrange(y1, y2, 1);
            y = [xx; yy];
        end

        % Unique function
        function [pop, fi] = unifun(~, x, f)
            [pop, fi] = deal(x, f);
        end

        % Print function
        function y = prifun(~, x)
            y = fprintf('%f %f ', x(1), x(2));
        end

        % Random range of values
        function y = ranrange(~, a, b, n)
            y = a + (b - a) * rand(n, 1);
        end

        % Get boundaries of CSFF
        function [x1,dx,x2, y1,dy,y2] = getbounds(app)
            x1 = app.fitbnds(1,1);
            dx = app.fitbnds(1,2);
            x2 = app.fitbnds(1,3);
            y1 = app.fitbnds(2,1);
            dy = app.fitbnds(2,2);
            y2 = app.fitbnds(2,3);
        end

    end

    % Fitness functions
    methods (Access = private)

        % Select fitness function as per GUI control
        function select_fitness_function(app)
            switch (app.FunctionDropDown.Value)
                case "Beale"
                    app.fitfunh = @app.beale;
                    app.fitbnds = [-5,0.05,5; -5,0.05,5];
                    app.fitopts = [3,0.5,0];
                case "Bukin nº6"
                    app.fitfunh = @app.bukin6;
                    app.fitbnds = [-15,0.05,0; -4,0.01,6];
                    app.fitopts = [-10,1,0];
                case "Camel (3-hump)"
                    app.fitfunh = @app.camel3;
                    app.fitbnds = [-3,0.01,3; -3,0.01,3];
                    app.fitopts = [0,0,0];
                case "Camel (6-hump)"
                    app.fitfunh = @app.camel6;
                    app.fitbnds = [-1,0.01,1; -1,0.01,1];
                    app.fitopts = [...
                        0.0898,-0.7126,-1.0316; ...
                        -0.0898,0.7126,-1.0316;];
                case "Damavandi"
                    app.fitfunh = @app.damavandi;
                    app.fitbnds = [0,0.05,14; 0,0.05,14];
                    app.fitopts = [2,2,0];
                case "Eggholder"
                    app.fitfunh = @app.eggholder;
                    app.fitbnds = [-1000,5,1000; -1000,5,1000];
                    app.fitopts = [512,404.2319,-959.6407];
                case "Himmelblau"
                    app.fitfunh = @app.himmelblau;
                    app.fitbnds = [-5,0.05,5; -5,0.05,5];
                    app.fitopts = [...
                        3,2,0; ...
                        -2.8,3.13,0; ...
                        -3.78,-3.28,0; ...
                        3.58,-1.848,0];
                case "Hölder Table"
                    app.fitfunh = @app.holdertable;
                    app.fitbnds = [-10,0.1,10; -10,0.1,10];
                    app.fitopts = [...
                        8.055,9.66,-19.2; ...
                        -8.055,9.66,-19.2; ...
                        8.055,-9.66,-19.2; ...
                        -8.055,-9.66,-19.2];
                case "Rastrigin"
                    app.fitfunh = @app.rastrigin;
                    app.fitbnds = [-5,0.05,5; -5,0.05,5];
                    app.fitopts = [1,1,0];
                case "Rosenbrock"
                    app.fitfunh = @app.rosenbrock;
                    app.fitbnds = [-2,0.01,2; -2,0.01,2];
                    app.fitopts = [1,1,0];
                case "Zirilli"
                    app.fitfunh = @app.zirilli;
                    app.fitbnds = [-2,0.01,2; -2,0.01,2];
                    app.fitopts = [-1.0465,0,-0.3523];
                otherwise
                    return;
            end
        end

        % Beale function
        function z = beale(~, x, y)
            a = 1.5 - x + x .* y;
            b = 2.25 - x + x .* y.^2;
            c = 2.625 - x + x .* y.^3;
            z = a.^2 + b.^2 + c.^2;
        end

        % Bukin nº6 function
        function z = bukin6(~, x, y)
            z = 100 * sqrt(abs(y - 0.01 * x.^2)) + 0.01 * abs(x + 10);
        end

        % Three-hump camel function
        function z = camel3(~, x, y)
            z = 2*x.^2 - 1.05*x.^4 + 1/6*x.^6 + x.*y + y.^2;
        end

        % Six-hump camel function
        function z = camel6(~, x, y)
            a = (4 - 2.1 * x.^2 + (x.^4) / 3) * x.^2;
            b = x .* y;
            c = (- 4 + 4 * y.^2) * y.^2;
            z = a + b + c;
        end

        % Damavandi function
        function z = damavandi(~, x, y)

            % Constants
            a = 1;
            b = pi;
            c = 2;
            d = 7;

            % First term
            pax = x - c;
            pay = y - c;
            sx = sin(b * pax);
            sy = sin(b * pay);
            kxy = sx .* sy ./ (b^2 * pax .* pay);
            
            % Second term
            pbx = x - d;
            pby = y - d;
            hxy = c + pbx.^2 + c*pby.^2;
            
            % Total
            z = (a - abs(kxy).^5) .* hxy;

        end

        % Eggholder function
        function z = eggholder(~, x, y)
            a = y + 47;
            b = abs(x/2 + a);
            c = abs(x - a);
            z = -a .* sin(sqrt(b)) - x .* sin(sqrt(c));
        end

        % Himmelblau function
        function z = himmelblau(~, x, y)
            z = (x.^2 + y - 11).^2 + (x + y.^2 - 7).^2;
        end

        % Hölder Table function
        function z = holdertable(~, x, y)
            a = 1.0 - sqrt(x.^2 + y.^2) / pi;
            z = -abs(sin(x) .* cos(y) .* exp(abs(a)));
        end

        % Rastrigin function
        function z = rastrigin(~, x, y)
            xx = x - 1; % Apply offset to the optimum point
            yy = y - 1; % Apply offset to the optimum point
            z = 20 + xx.^2 + yy.^2 - 10*(cos(2*pi*xx) + cos(2*pi*yy));
        end

        % Rosenbrock function
        function z = rosenbrock(~, x, y)
            z = 100 * (y - x.^2).^2 + (x - 1).^2;
        end

        % Zirilli function
        function z = zirilli(~, x, y)
            z = 0.25 * x.^4 - 0.5 * x.^2 + 0.1 * x + 0.5 * y.^2;
        end

    end

    % GUI auxiliary and plotting functions
    methods (Access = private)

        % Plot rastrigin function (on main axis)
        function plot_fitness_map(app)

            % Remove previous plot elements
            try delete(app.ph_fit); catch; end
            try delete(app.ph_best); catch; end

            % Build fitness map meshgrid
            [x1,dx,x2, y1,dy,y2] = app.getbounds();
            [x, y] = meshgrid(x1:dx:x2, y1:dy:y2);

            % Evaluate fitness map
            z = app.fitfunh(x,y);

            % Get maximum and minimum fitness values
            maxv = max(max(z));
            minv = min(min(z));
            app.fitmaxv = max([10, maxv]);
            app.fitminv = max([0, minv]) - minv;

            % Plot fitness map
            app.ph_fit = surf(app.UIAxes2D, ...
                x, y, z, 'LineStyle', 'none');

            % Get best fitness point(s)
            xi = app.fitopts(:,1);
            yi = app.fitopts(:,2);
            zi = app.fitopts(:,3);

            % Plot best fitness point
            app.ph_best = plot3(app.UIAxes2D, ...
                xi, yi, zi + app.fitmaxv, 'yo');

            % Canvas styling
            view(app.UIAxes2D, 0,90);
            xlim(app.UIAxes2D, [min(min(x)), max(max(x))]);
            ylim(app.UIAxes2D, [min(min(y)), max(max(y))]);

            % Plot legend
            app.plot_text_legend();

        end

        % Print the main legend text on the main plot canvas
        function plot_text_legend(app)
            
            % Text to be displayed
            textString = {...
                'o target optimum';...
                '☆ elites';...
                'x mutants';...
                'v descendants';...
                '+ newcomers';...
                };
            
            % Draw everything pending (to detect the correct limits)
            drawnow;

            % Get the axes limits
            xlims = get(app.UIAxes2D, 'XLim');
            ylims = get(app.UIAxes2D, 'YLim');
            zlims = get(app.UIAxes2D, 'ZLim');
            
            % Calculate position for top-left corner
            xPos = xlims(1); % Leftmost x-value
            yPos = ylims(2); % Topmost y-value
            zPos = zlims(2); % Highest z-value to ensure text is on top
            
            % Adjustments for padding/margin (optional, adjust as needed)
            xPad = diff(xlims) * 0.02; % 2% of the axes width
            yPad = diff(ylims) * 0.02; % 2% of the axes height
            
            % Since we're using a top-down view, set zPos slightly above 
            % the current top to ensure visibility
            zPos = zPos + abs(zPos * 0.1); % Adjust as needed
            
            % Remove previous plot elements
            try delete(app.ph_leg); catch; end

            % Place the text on the plot
            app.ph_leg = text(app.UIAxes2D, ...
                xPos + xPad, yPos - yPad, zPos, ...
                textString, ...
                'FontSize', 12, ...
                'FontName', 'Courier New', ... % Monospace font
                'Color', 'yellow', ... % Text color
                'VerticalAlignment', 'top', ... % Align to top
                'HorizontalAlignment', 'left'); % Align to left
        end

        % Plot population (on main axis)
        function plot_population(app, pop, fi, g, ne, nm, nd)

            % Title
            title(app.UIAxes2D, sprintf('Generation: %03.0f', g));

            % Current population size
            np = length(pop);
            
            % Remove previous plot individuals
            try for i=1:np, delete(app.ph_pop{i}); end; catch; end

            % Preallocate handles
            app.ph_pop = cell(np,1); 

            % Plot individuals
            for i=1:np
    
                % Select plotting marker
                if i<=ne, marker = 'wp'; % Elites
                elseif i<=ne+nm, marker = 'wx'; % Mutants
                elseif i<=ne+nm+nd, marker = 'wv'; % Descendants
                else, marker = 'w+'; % Newcomers
                end
    
                % Plot individual
                x = pop{i}(1);
                y = pop{i}(2);
                z = fi(i) + app.fitmaxv; % Hover over the fitness map
                app.ph_pop{i} = plot3(app.UIAxes2D, ...
                    x, y, z, marker, 'MarkerSize', 4);
    
                % Save legend ticks
                if i==ne, lh(1) = app.ph_pop{i}; % Elite
                elseif i==ne+nm, lh(2) = app.ph_pop{i}; % Mutant
                elseif i==ne+nm+nd, lh(3) = app.ph_pop{i}; % Descendant
                elseif i==ne+nm+nd+1, lh(4) = app.ph_pop{i}; % Newcomer
                end
    
            end
    
            % Do events
            drawnow;
            
            % Wait
            pause(0.5);

        end

        % Plot new fitness value (on secondary axis)
        function plot_fitness(app, g, fi)
            ye = mean(fi);
            ya = max(fi);
            yi = min(fi);
            err = std(fi);
            app.ph_fi_std{g} = errorbar(app.UIAxesFit, g, ye, err, 'b');
            app.ph_fi_max{g} = plot(app.UIAxesFit, g, ya, 'rv');
            app.ph_fi_min{g} = plot(app.UIAxesFit, g, yi, 'r^');
        end

        % Reset population plot elements (on main axis)
        function plot_population_reset(app)
            for i=1:length(app.ph_pop)
                delete(app.ph_pop{i});
            end
        end

        % Reset fitness plot elements (on secondary axis)
        function plot_fitness_reset(app)
            for i=1:length(app.ph_fi_std)
                delete(app.ph_fi_std{i});
            end
            for i=1:length(app.ph_fi_min)
                delete(app.ph_fi_min{i});
            end
            for i=1:length(app.ph_fi_max)
                delete(app.ph_fi_max{i});
            end
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            % Position GUI on the center of the screen
            movegui(app.UIFigure, "center");

            % Process all pending work
            drawnow;

            % Select new fitness function
            app.select_fitness_function();

            % Plot new fitness map
            app.plot_fitness_map();
            
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            
            % Get values from UI controls
            ui_ng = app.GenerationsSpinner.Value; % Number of generations
            ui_np = app.PopulationSpinner.Value; % Population size
            ui_ne = app.ElitesSpinner.Value; % Number of elites
            ui_nm = app.MutantsSlider.Value; % Percentage of mutants
            ui_nn = app.NewcomersSlider.Value; % Percentage of newcomers
            ui_na = app.ParentsSlider.Value; % Percentage of parents

            % Define AGA algorithm parameters
            ng = ui_ng; % Number of generations
            np = ui_np; % Population size
            ne = ui_ne; % Number of elites
            nm = floor(np*ui_nm/100); % Number of mutants
            nn = floor(np*ui_nn/100); % Number of newcomers
            na = floor(np*ui_na/100); % Number of breeders

            % Target fitness value
            goal = -Inf; % -Inf will ensure all generations are done
            
            % Randomize random seed
            rng('shuffle'); % We don't want repeatability in the heuristic

            % Reset status switches and plotting stuff
            app.stop_simulation = false;
            app.plot_population_reset();
            app.plot_fitness_reset();

            % Execute Genetic Algorithm (GA)
            [~] = app.aga(goal, ng, np, ne, nm, nn, na);


        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.stop_simulation = true;
        end

        % Value changed function: FunctionDropDown
        function FunctionDropDownValueChanged(app, event)

            % Stop any pending simulation
            app.stop_simulation = true;

            % Select new fitness function
            app.select_fitness_function();

            % Plot new fitness map
            app.plot_fitness_map();

            % Reset status switches and plotting stuff
            app.plot_population_reset();
            app.plot_fitness_reset();
            
        end

        % Value changed function: ColorScaleSwitch
        function ColorScaleSwitchValueChanged(app, event)
            value = app.ColorScaleSwitch.Value;
            app.UIAxes2D.ColorScale = value;
        end

        % Value changed function: FitScaleSwitch
        function FitScaleSwitchValueChanged(app, event)
            value = app.FitScaleSwitch.Value;
            app.UIAxesFit.YScale = value;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'GUI AGA | Genetic Algorithm';
            app.UIFigure.Icon = fullfile(pathToMLAPP, 'icon.png');
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayoutLeft = uigridlayout(app.LeftPanel);
            app.GridLayoutControls = uigridlayout(app.GridLayoutLeft);
            app.FunctionDropDownLabel = uilabel(app.GridLayoutControls);
            app.FunctionDropDownLabel.Text = 'Function';
            app.FunctionDropDown = uidropdown(app.GridLayoutControls);
            app.FunctionDropDown.Items = {'Beale', 'Bukin nº6', 'Camel (3-hump)', 'Camel (6-hump)', 'Damavandi', 'Eggholder', 'Himmelblau', 'Hölder Table', 'Rastrigin', 'Rosenbrock', 'Zirilli'};
            app.FunctionDropDown.ValueChangedFcn = createCallbackFcn(app, @FunctionDropDownValueChanged, true);
            app.FunctionDropDown.Placeholder = 'Cost Function';
            app.FunctionDropDown.Value = 'Rastrigin';
            app.GenerationsSpinnerLabel = uilabel(app.GridLayoutControls);
            app.GenerationsSpinnerLabel.Text = 'Generations';
            app.GenerationsSpinner = uispinner(app.GridLayoutControls);
            app.GenerationsSpinner.Step = 10;
            app.GenerationsSpinner.Limits = [1 1000];
            app.GenerationsSpinner.RoundFractionalValues = 'on';
            app.GenerationsSpinner.ValueDisplayFormat = '%.0f';
            app.GenerationsSpinner.Placeholder = 'Iterations';
            app.GenerationsSpinner.Value = 10;
            app.PopulationSpinnerLabel = uilabel(app.GridLayoutControls);
            app.PopulationSpinnerLabel.Text = 'Population';
            app.PopulationSpinner = uispinner(app.GridLayoutControls);
            app.PopulationSpinner.Step = 10;
            app.PopulationSpinner.Limits = [1 1000];
            app.PopulationSpinner.RoundFractionalValues = 'on';
            app.PopulationSpinner.ValueDisplayFormat = '%.0f';
            app.PopulationSpinner.Placeholder = 'Individuals';
            app.PopulationSpinner.Value = 10;
            app.ElitesSpinnerLabel = uilabel(app.GridLayoutControls);
            app.ElitesSpinnerLabel.Text = 'Elites';
            app.ElitesSpinner = uispinner(app.GridLayoutControls);
            app.ElitesSpinner.Limits = [0 100];
            app.ElitesSpinner.RoundFractionalValues = 'on';
            app.ElitesSpinner.ValueDisplayFormat = '%.0f';
            app.ElitesSpinner.Value = 1;
            app.MutantsSliderLabel = uilabel(app.GridLayoutControls);
            app.MutantsSliderLabel.Text = 'Mutants';
            app.MutantsSlider = uislider(app.GridLayoutControls);
            app.MutantsSlider.Tooltip = {'Percentage'};
            app.MutantsSlider.Value = 30;
            app.ParentsSliderLabel = uilabel(app.GridLayoutControls);
            app.ParentsSliderLabel.Text = 'Parents';
            app.ParentsSlider = uislider(app.GridLayoutControls);
            app.ParentsSlider.Tooltip = {'Percentage'};
            app.ParentsSlider.Value = 60;
            app.NewcomersSliderLabel = uilabel(app.GridLayoutControls);
            app.NewcomersSliderLabel.Text = 'Newcomers';
            app.NewcomersSlider = uislider(app.GridLayoutControls);
            app.NewcomersSlider.Tooltip = {'Percentage'};
            app.NewcomersSlider.Value = 10;
            app.FitScaleSwitchLabel = uilabel(app.GridLayoutControls);
            app.FitScaleSwitchLabel.Text = 'Fit Scale';
            app.FitScaleSwitch = uiswitch(app.GridLayoutControls, 'slider');
            app.FitScaleSwitch.Items = {'Lin', 'Log'};
            app.FitScaleSwitch.ItemsData = {'linear', 'log'};
            app.FitScaleSwitch.ValueChangedFcn = createCallbackFcn(app, @FitScaleSwitchValueChanged, true);
            app.FitScaleSwitch.Value = 'linear';
            app.ColorScaleSwitchLabel = uilabel(app.GridLayoutControls);
            app.ColorScaleSwitchLabel.Text = 'Color Scale';
            app.ColorScaleSwitch = uiswitch(app.GridLayoutControls, 'slider');
            app.ColorScaleSwitch.Items = {'Lin', 'Log'};
            app.ColorScaleSwitch.ItemsData = {'linear', 'log'};
            app.ColorScaleSwitch.ValueChangedFcn = createCallbackFcn(app, @ColorScaleSwitchValueChanged, true);
            app.ColorScaleSwitch.Value = 'linear';
            app.GridLayoutRunStop = uigridlayout(app.GridLayoutLeft);
            app.RunButton = uibutton(app.GridLayoutRunStop, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.StopButton = uibutton(app.GridLayoutRunStop, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayoutRight = uigridlayout(app.RightPanel);
            app.UIAxes2D = uiaxes(app.GridLayoutRight);
            title(app.UIAxes2D, 'Search space')
            colormap(app.UIAxes2D, 'abyss')
            app.UIAxesFit = uiaxes(app.GridLayoutRight);
            title(app.UIAxesFit, 'Fitness (max / min / std)')
            xlabel(app.UIAxesFit, 'Generations')
            zlabel(app.UIAxesFit, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_AGA

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end